classdef ISSAAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ISSALabel                       matlab.ui.control.Label
        EmailISSAhelper2024outlookcomLabel  matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        StressStateAnalysisPanel        matlab.ui.container.Panel
        ANALYSISButton                  matlab.ui.control.StateButton
        RotationAngleACWEditField       matlab.ui.control.NumericEditField
        RotationAngleACWEditFieldLabel  matlab.ui.control.Label
        RotationButtonGroup             matlab.ui.container.ButtonGroup
        CFButton                        matlab.ui.control.RadioButton
        CEButton                        matlab.ui.control.RadioButton
        CAButton                        matlab.ui.control.RadioButton
        KnownStressStatesPanel          matlab.ui.container.Panel
        DEMONSTRATIONButton             matlab.ui.control.StateButton
        DRAWINGButton                   matlab.ui.control.StateButton
        angleECx                        matlab.ui.control.NumericEditField
        xOELabel                        matlab.ui.control.Label
        State2y                         matlab.ui.control.NumericEditField
        State2x                         matlab.ui.control.NumericEditField
        Point1EditFieldLabel_2          matlab.ui.control.Label
        State1y                         matlab.ui.control.NumericEditField
        State1x                         matlab.ui.control.NumericEditField
        Point1EditFieldLabel            matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        DesignerHongkaiTaoQiZhaoJiayanWangChengyangZhuJingranLiLabel  matlab.ui.control.Label
        MohrsCircleLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Theta1graphical                 matlab.ui.control.TextArea
        InfinitesimalElementLabel       matlab.ui.control.Label
        taoyx                           matlab.ui.control.TextArea
        Sigmay                          matlab.ui.control.TextArea
        PointAEditFieldLabel_2          matlab.ui.control.Label
        PointDEditFieldLabel_10         matlab.ui.control.Label
        PointDEditFieldLabel_9          matlab.ui.control.Label
        taoy1                           matlab.ui.control.TextArea
        Sigmay1                         matlab.ui.control.TextArea
        PointDEditFieldLabel_8          matlab.ui.control.Label
        PointDEditFieldLabel_7          matlab.ui.control.Label
        PointDEditFieldLabel_6          matlab.ui.control.Label
        PointDEditFieldLabel_5          matlab.ui.control.Label
        PointDEditFieldLabel_4          matlab.ui.control.Label
        taoxy                           matlab.ui.control.TextArea
        Sigmax                          matlab.ui.control.TextArea
        PointAEditFieldLabel            matlab.ui.control.Label
        taox1                           matlab.ui.control.TextArea
        Sigmax1                         matlab.ui.control.TextArea
        PointDEditFieldLabel_3          matlab.ui.control.Label
        PointDEditFieldLabel_2          matlab.ui.control.Label
        EditField_2Label_3              matlab.ui.control.Label
        Shearmin                        matlab.ui.control.NumericEditField
        EditField_2Label_2              matlab.ui.control.Label
        Shearmax                        matlab.ui.control.NumericEditField
        Stress1                         matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        Stress3                         matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        PointDEditFieldLabel            matlab.ui.control.Label
        Stress2                         matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Theta2analytical                matlab.ui.control.TextArea
        Theta2graphical                 matlab.ui.control.TextArea
        FCxLabel_3                      matlab.ui.control.Label
        Theta1analytical                matlab.ui.control.TextArea
        FCxanalytical                   matlab.ui.control.TextArea
        FCxgraphical                    matlab.ui.control.TextArea
        AngleLabel                      matlab.ui.control.Label
        GraphicalLabel                  matlab.ui.control.Label
        AnalyticalLabel                 matlab.ui.control.Label
        FCx180Label                     matlab.ui.control.Label
        FCxLabel_2                      matlab.ui.control.Label
        FCxLabel                        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        m % Description
        n % Description
    end
    
    methods (Access = private)
        
        function results = rotaterec(app)
            %删除上次画图痕迹
            hold(app.UIAxes2,"off");
            
            theta=app.RotationAngleACWEditField.Value*pi/180;
            
            for i=0:0.01:theta
                  
                x=[-0.5 -0.5 0.5 0.5 -0.5];
                y=[-0.5 0.5 0.5 -0.5 -0.5];
                xcenter=0;
                ycenter=0;
                R=[cos(i) -sin(i); sin(i) cos(i)];
                v=[x;y];
                center=repmat([xcenter;ycenter],1,length(x));
                vo=R*(v-center)+center;
                xrotated=vo(1,:);
                yrotated=vo(2,:);
                rectangle(app.UIAxes2,'Position',[-0.5,-0.5,1,1],'EdgeColor',[0.76,0.78,0.83]);
                hold(app.UIAxes2,"on")
                p=plot(app.UIAxes2,xrotated,yrotated,'r-','Color',[1,1,1]);
                line(app.UIAxes2,[0,0.5],[0,0],'color',[0,1,1]);
                l=line(app.UIAxes2,[0,0.5*cos(i)],[0,0.5*sin(i)],'color',[0,1,1]);
                pause(0.001);
                delete(l);
                delete(p);
            end
            cla(app.UIAxes2);

            x=[-0.5 -0.5 0.5 0.5 -0.5];
            y=[-0.5 0.5 0.5 -0.5 -0.5];
            xcenter=0;
            ycenter=0;
            
            R=[cos(theta) -sin(theta); sin(theta) cos(theta)];
            v=[x;y];
            center=repmat([xcenter;ycenter],1,length(x));
            vo=R*(v-center)+center;
            xrotated=vo(1,:);
            yrotated=vo(2,:);
            
            rectangle(app.UIAxes2,'Position',[-0.5,-0.5,1,1],'EdgeColor',[0.51,0.56,0.68]);
            hold(app.UIAxes2,"on")
            plot(app.UIAxes2,xrotated,yrotated,'r-','Color',[1,1,1],'LineWidth',1);

            line(app.UIAxes2,[0,0.5],[0,0],'color',[0,1,1]);
            line(app.UIAxes2,[0,0.5*cos(theta)],[0,0.5*sin(theta)],'color',[0,1,1]);
            text(app.UIAxes2,0.2*cos(theta/2),0.2*sin(theta/2),0,[' ',num2str(app.RotationAngleACWEditField.Value),'° '],'Color',[1,1,1],'FontSize',10,AffectAutoLimits="on");
            hold(app.UIAxes2,"on");
            %x1标注
            quiver(app.UIAxes2,0.5*cos(theta),0.5*sin(theta),0.5*cos(theta),0.5*sin(theta),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.82,0.78,1.00]);
            quiver(app.UIAxes2,0.6*cos(theta)-0.25*sin(theta),0.6*sin(theta)+0.25*cos(theta),0.5*sin(theta),-0.5*cos(theta),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.58,0.69,1]);
            text(app.UIAxes2,0.5*cos(theta+pi/20)+0.5*cos(theta+pi/20),0.5*sin(theta+pi/20)+0.5*sin(theta+pi/20),'σx1','Color',[0.82,0.78,1.00],'HorizontalAlignment','center','VerticalAlignment','middle','AffectAutoLimits','on');
            text(app.UIAxes2,0.7*cos(theta-pi/20)+0.25*sin(theta-pi/20),0.7*sin(theta-pi/20)-0.25*cos(theta-pi/20),'τx1','Color',[0.58,0.69,1],'HorizontalAlignment','center','VerticalAlignment','middle','AffectAutoLimits','on');
            %y1标注
            quiver(app.UIAxes2,0.5*cos(theta+pi/2),0.5*sin(theta+pi/2),0.5*cos(theta+pi/2),0.5*sin(theta+pi/2),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.82,0.78,1.00]);
            quiver(app.UIAxes2,0.6*cos(theta+pi/2)+0.25*sin(theta+pi/2),0.6*sin(theta+pi/2)-0.25*cos(theta+pi/2),-0.5*sin(theta+pi/2),0.5*cos(theta+pi/2),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.58,0.69,1]);
            text(app.UIAxes2,0.5*cos(theta+pi/2+pi/20)+0.5*cos(theta+pi/2+pi/20),0.5*sin(theta+pi/2+pi/20)+0.5*sin(theta+pi/2+pi/20),'σy1','Color',[0.82,0.78,1.00],'HorizontalAlignment','center','VerticalAlignment','middle','AffectAutoLimits','on');
            text(app.UIAxes2,0.7*cos(theta+pi/2+pi/20)-0.25*sin(theta+pi/2+pi/20),0.7*sin(theta+pi/2+pi/20)+0.25*cos(theta+pi/2+pi/20),'τy1','Color',[0.58,0.69,1],'HorizontalAlignment','center','VerticalAlignment','middle','AffectAutoLimits','on');
        end
        
        
        function results = Theta1Drawing(app)
            x1=app.State1x.Value;
            y1=app.State1y.Value;
            x2=app.State2x.Value;
            y2=app.State2y.Value;
            Cx=((x1-x2)*(x1+x2)-(y2-y1)*(y1+y2))/(2*(x1-x2));
            Cy=0;
            R=(y1^2+(((x1-x2)^2+(y2-y1)*(y1+y2))/(2*(x1-x2)))^2)^(1/2);
         
            ECx=2*(app.angleECx.Value)*pi/180;
            xx=(x1-Cx)*cos(ECx)-(y1-Cy)*sin(ECx)+Cx;
            xy=(y1-Cy)*cos(ECx)+(x1-Cx)*sin(ECx)+Cy;
            %计算∠ACσ
            sigma=[1,0];
            CA=[xx-Cx,xy-Cy];
            ACsigma=acos(dot(CA,sigma)/(norm(CA)*norm(sigma)));

            if xy>0
                sp=0.01;
                ACsigma1=ACsigma;
            else
                sp=-0.01;
                ACsigma1=-ACsigma;
            end

            for arci=0:sp:ACsigma1
                arcxe=0.1*((Cx+R-Cx)*cos(arci+sp)-(0-Cy)*sin(arci+sp))+Cx; 
                arcye=0.1*((0-Cy)*cos(arci+sp)+(Cx+R-Cx)*sin(arci+sp))+Cy;
                arcxa=0.1*((Cx+R-Cx)*cos(arci)-(0-Cy)*sin(arci))+Cx; 
                arcya=0.1*((0-Cy)*cos(arci)+(Cx+R-Cx)*sin(arci))+Cy;
                line(app.UIAxes,[arcxa,arcxe],[arcya,arcye],'Linewidth', 0.8,'color',[1,0.38,0.1]); %画弧
            end
            text(app.UIAxes,0.2*((Cx+R-Cx)*cos(ACsigma1/2)-(0-Cy)*sin(ACsigma1/2))+Cx,0.2*((0-Cy)*cos(ACsigma1/2)+(Cx+R-Cx)*sin(ACsigma1/2))+Cy,[num2str(roundn(-ACsigma1/pi*180,-2)),'° '],'color',[1,0.38,0.1],'FontSize',14,'VerticalAlignment','middle','HorizontalAlignment','center',AffectAutoLimits="on")
            app.Theta1graphical.Value=num2str(roundn(real(-ACsigma1*180/pi),-2));
            app.Theta1analytical.Value=num2str(roundn(real(-ACsigma1*180/pi/2),-2));
        end

        function results = Theta2Drawing(app)
            x1=app.State1x.Value;
            y1=app.State1y.Value;
            x2=app.State2x.Value;
            y2=app.State2y.Value;
            Cx=((x1-x2)*(x1+x2)-(y2-y1)*(y1+y2))/(2*(x1-x2));
            Cy=0;
            R=(y1^2+(((x1-x2)^2+(y2-y1)*(y1+y2))/(2*(x1-x2)))^2)^(1/2);
         
            ECy=2*(app.angleECx.Value)*pi/180+pi;
            %B点坐标
            yx=(x1-Cx)*cos(ECy)-(y1-Cy)*sin(ECy)+Cx;
            yy=(y1-Cy)*cos(ECy)+(x1-Cx)*sin(ECy)+Cy;
            %计算∠BCσ
            sigma=[1,0];
            CB=[yx-Cx,yy-Cy];
            BCsigma=acos(dot(CB,sigma)/(norm(CB)*norm(sigma)));
            
            if yy>0
                sp=0.01;
                BCsigma1=BCsigma;
            else
                sp=-0.01;
                BCsigma1=-BCsigma;
            end

            for arci=0:sp:BCsigma1
                arcxe=0.1*((Cx-R-Cx)*cos(arci+sp)-(0-Cy)*sin(arci+sp))+Cx; 
                arcye=0.1*((0-Cy)*cos(arci+sp)+(Cx-R-Cx)*sin(arci+sp))+Cy;
                arcxa=0.1*((Cx-R-Cx)*cos(arci)-(0-Cy)*sin(arci))+Cx; 
                arcya=0.1*((0-Cy)*cos(arci)+(Cx-R-Cx)*sin(arci))+Cy;
                line(app.UIAxes,[arcxa,arcxe],[arcya,arcye],'Linewidth', 0.8,'color',[0.2,0.63,0.79]); %画弧
            end
            text(app.UIAxes,0.2*((Cx-R-Cx)*cos(BCsigma1/2)-(0-Cy)*sin(BCsigma1/2))+Cx,0.2*((0-Cy)*cos(BCsigma1/2)+(Cx-R-Cx)*sin(BCsigma1/2))+Cy,[num2str(roundn(-BCsigma1/pi*180,-2)),'° '],'color',[0.2,0.63,0.79],'FontSize',14,'VerticalAlignment','middle','HorizontalAlignment','center',AffectAutoLimits="on")
            app.Theta2graphical.Value=num2str(roundn(real(-BCsigma1*180/pi),-2));
            app.Theta2analytical.Value=num2str(roundn(real(-BCsigma1*180/pi/2),-2));
        end

        function results = RegularFunction(app)
            x1=app.State1x.Value;
            y1=app.State1y.Value;
            x2=app.State2x.Value;
            y2=app.State2y.Value;
            %输入两点坐标
            
            Cx=((x1-x2)*(x1+x2)-(y2-y1)*(y1+y2))/(2*(x1-x2));
            Cy=0;
            R=(y1^2+(((x1-x2)^2+(y2-y1)*(y1+y2))/(2*(x1-x2)))^2)^(1/2);
            
            theta=0:0.01:2*pi;
            x=Cx+R*cos(theta);
            y=Cy+R*sin(theta);
    
            %A点坐标
            ECx=2*(app.angleECx.Value)*pi/180;
            xx=(x1-Cx)*cos(ECx)-(y1-Cy)*sin(ECx)+Cx;
            xy=(y1-Cy)*cos(ECx)+(x1-Cx)*sin(ECx)+Cy;
            app.Sigmax.Value=num2str(roundn(real(xx),-2));
            app.taoxy.Value=num2str(roundn(real(xy),2));
            app.Sigmay.Value=num2str(roundn(real(2*Cx-xx),-2));
            app.taoyx.Value=num2str(roundn(real(-xy),-2));

            stressmax=Cx+R;
            stressmin=Cx-R;
            
            %设置坐标轴范围
            xlim(app.UIAxes,[Cx-5/4*R,Cx+5/4*R]);
            ylim(app.UIAxes,[Cy-5/4*R,Cy+5/4*R]);

            plot(app.UIAxes,x,y,x1,y1,'.',x2,y2,'.',stressmax,0,'.',stressmin,0,'.',Cx,R,'.',Cx,-R,'.',Cx,Cy,'.',xx,xy,'.',2*Cx-xx,-xy,'.','color',[0.1,0.1,0.44],'LineWidth',1,'Markersize',14);%莫尔圆（深蓝色）

            line(app.UIAxes,[(-5/4-Cx/(Cx-xx))*(xx-Cx),(5/4-Cx/(Cx-xx))*(xx-Cx)],[-5/4*xy,5/4*xy],'Linewidth', 1,'color',[0.18,0.55,0.34]);%x、y轴（海绿色）
            line(app.UIAxes,[x1,Cx],[y1,Cy],'Linewidth', 1.5,'color',[0.42,0.35,0.80]);%EC线（石板蓝）
            line(app.UIAxes,[x2,Cx],[y2,Cy],'Linewidth', 1.5,'color',[0.53,0.15,0.34]);%FC线（草莓色）
                
            %标注σmax、σmin
            text(app.UIAxes,stressmax+R/20,0,'σmax','FontSize',16,'color',[0.1,0.1,0.44],'VerticalAlignment','bottom','HorizontalAlignment','left',AffectAutoLimits="on")
            text(app.UIAxes,stressmin-R/20,0,'σmin','FontSize',16,'color',[0.1,0.1,0.44],'VerticalAlignment','bottom','HorizontalAlignment','right',AffectAutoLimits="on")
            %标注τmax、τmin
            text(app.UIAxes,Cx,21*R/20,'τmax','FontSize',16,'color',[0.1,0.1,0.44],'VerticalAlignment','middle','HorizontalAlignment','center',AffectAutoLimits="on")
            text(app.UIAxes,Cx,-21*R/20,'τmin','FontSize',16,'color',[0.1,0.1,0.44],'VerticalAlignment','middle','HorizontalAlignment','center',AffectAutoLimits="on")
            
            %标注E、F
            if x1>=Cx&&y1>=Cy
                dx1=R/40;dy1=R/40;
            elseif x1>Cx&&y1<Cy
                dx1=R/40;dy1=-R/40;
            elseif x1<=Cx&&y1<=Cy
                dx1=-R/40;dy1=-R/40;
            elseif x1<Cx&&y1>Cy
                dx1=-R/40; dy1=R/40;
            end
            if x2>=Cx&&y2>=Cy
                dx2=R/40;dy2=R/40;
            elseif x2>Cx&&y2<Cy
                dx2=R/40;dy2=-R/40;
            elseif x2<=Cx&&y2<=Cy
                dx2=-R/40;dy2=-R/40;
            elseif x2<Cx&&y2>Cy
                dx2=-R/40; dy2=R/40;
            end
            text(app.UIAxes,x1+dx1,y1+dy1,'E','FontSize',16,'color',[0.42,0.35,0.80],'VerticalAlignment','middle','HorizontalAlignment','center',AffectAutoLimits="on");
            text(app.UIAxes,x2+dx2,y2+dy2,'F','FontSize',16,'color',[0.53,0.15,0.34],'VerticalAlignment','middle','HorizontalAlignment','center',AffectAutoLimits="on");
           
            %标注x(A)、y(B)、C
            text(app.UIAxes,(5/4-Cx/(Cx-xx))*(xx-Cx),5/4*xy,' x(A) ','FontSize',16,'VerticalAlignment','middle','HorizontalAlignment','left',AffectAutoLimits="on");
            text(app.UIAxes,(-5/4-Cx/(Cx-xx))*(xx-Cx),-5/4*xy,' y(B) ','FontSize',16,'VerticalAlignment','middle','HorizontalAlignment','left',AffectAutoLimits="on");
            text(app.UIAxes,Cx,Cy,' C','FontSize',16,'VerticalAlignment','top','HorizontalAlignment','left',AffectAutoLimits="on");
            
            %画∠Theta1,Theta2
            if xy~=0
                Theta1Drawing(app);
                Theta2Drawing(app);
            end
            
            %画右侧示意图
            cla(app.UIAxes2,'reset')
            xlim(app.UIAxes2,[-1,1]);
            ylim(app.UIAxes2,[-1,1]);
            pbaspect(app.UIAxes2,[1,1,1]);
            axis(app.UIAxes2,'off');
            rectangle(app.UIAxes2,'Position',[-0.5,-0.5,1,1],'EdgeColor',[1,1,1]);
            hold(app.UIAxes2,"on");
            quiver(app.UIAxes2,0.5*cos(0),0.5*sin(0),0.5*cos(0),0.5*sin(0),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.82,0.78,1.00]);
            quiver(app.UIAxes2,0.6*cos(0)-0.25*sin(0),0.6*sin(0)+0.25*cos(0),0.5*sin(0),-0.5*cos(0),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.58,0.69,1]);
            text(app.UIAxes2,0.5*cos(0+pi/20)+0.5*cos(0+pi/20),0.5*sin(0+pi/20)+0.5*sin(0+pi/20),'σx','Color',[0.82,0.78,1.00],'HorizontalAlignment','center','VerticalAlignment','middle','AffectAutoLimits','on');
            text(app.UIAxes2,0.6*cos(0-pi/20)+0.25*sin(0-pi/20),0.6*sin(0-pi/20)-0.25*cos(0-pi/20),'τxy','Color',[0.58,0.69,1],'HorizontalAlignment','left','VerticalAlignment','middle','AffectAutoLimits','on');
            
            quiver(app.UIAxes2,0.5*cos(pi/2),0.5*sin(pi/2),0.5*cos(pi/2),0.5*sin(pi/2),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.82,0.78,1.00]);
            quiver(app.UIAxes2,0.6*cos(pi/2)+0.25*sin(pi/2),0.6*sin(pi/2)-0.25*cos(pi/2),-0.5*sin(pi/2),0.5*cos(pi/2),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.58,0.69,1]);
            text(app.UIAxes2,0.5*cos(pi/2+pi/20)+0.5*cos(pi/2+pi/20),0.5*sin(pi/2+pi/20)+0.5*sin(pi/2+pi/20),'σy','Color',[0.82,0.78,1.00],'HorizontalAlignment','center','VerticalAlignment','middle','AffectAutoLimits','on');
            text(app.UIAxes2,0.6*cos(pi/2-pi/20)-0.3*sin(pi/2-pi/20),0.6*sin(pi/2-pi/20)+0.25*cos(pi/2-pi/20),'τyx','Color',[0.58,0.69,1],'HorizontalAlignment','right','VerticalAlignment','middle','AffectAutoLimits','on');

            %相关数据计算、显示
            CA=[xx-Cx,xy-Cy];
            CF=[x2-Cx,y2-Cy];
            FCX=acos(dot(CA,CF)/(norm(CA)*norm(CF)));

            %标注stress、strain
            if stressmin>=0
                app.Stress1.Value=stressmax;
                app.Stress2.Value=stressmin;
                app.Stress3.Value=0;
            elseif stressmax<=0
                app.Stress1.Value=0;
                app.Stress2.Value=stressmax;
                app.Stress3.Value=stressmin;
            else
                app.Stress1.Value=stressmax;
                app.Stress2.Value=0;
                app.Stress3.Value=stressmin;
            end
            app.Shearmax.Value=R;
            app.Shearmin.Value=-R;

            %计算∠ACσ
            app.FCxgraphical.Value=num2str(roundn(real(FCX*180/(pi)),-2));
            app.FCxanalytical.Value=num2str(roundn(real(FCX*180/(pi)/2),-2));
            

            text(app.UIAxes,Cx,Cy-R/12,['(',num2str(Cx),',',num2str(Cy),')'],'FontSize',16,'HorizontalAlignment','center');

        end
        
        function results = RegularRotateAnalysis(app)
            x1=app.State1x.Value;
            y1=app.State1y.Value;
            x2=app.State2x.Value;
            y2=app.State2y.Value;
         
            Cx=((x1-x2)*(x1+x2)-(y2-y1)*(y1+y2))/(2*(x1-x2));
            Cy=0;
            R=(y1^2+(((x1-x2)^2+(y2-y1)*(y1+y2))/(2*(x1-x2)))^2)^(1/2);

            Alphatimes2=2*(app.RotationAngleACWEditField.Value)*pi/180;

            %定义每帧步长
            if Alphatimes2>=0
                stp=0.01;
            else
                stp=-0.01;
            end
               
            for i=0:stp:Alphatimes2
                         ax=(app.m-Cx)*cos(i)-(app.n-Cy)*sin(i)+Cx;
                         ay=(app.n-Cy)*cos(i)+(app.m-Cx)*sin(i)+Cy;
                         arcx1=0.17*((app.m-Cx)*cos(i-stp)-(app.n-Cy)*sin(i-stp))+Cx; %画弧度
                         arcy1=0.17*((app.n-Cy)*cos(i-stp)+(app.m-Cx)*sin(i-stp))+Cy;
                         arcx2=0.17*((app.m-Cx)*cos(i)-(app.n-Cy)*sin(i))+Cx; %画弧度
                         arcy2=0.17*((app.n-Cy)*cos(i)+(app.m-Cx)*sin(i))+Cy;

                         h=line(app.UIAxes,[2*Cx-ax,ax],[-ay,ay],'Linewidth', 1.5,'color',[1,0.39,0.28]); %旋转动画
                         line(app.UIAxes,[arcx1,arcx2],[arcy1,arcy2],'Linewidth', 0.8,'color',[0,0,1]); %画弧
                         
                         pause(0.001);
                         delete(h);
            end
            ax=(app.m-Cx)*cos(Alphatimes2)-(app.n-Cy)*sin(Alphatimes2)+Cx;
            ay=(app.n-Cy)*cos(Alphatimes2)+(app.m-Cx)*sin(Alphatimes2)+Cy;
            line(app.UIAxes,[2*Cx-ax,ax],[-ay,ay],'Linewidth', 1.5,'color',[1,0,0]);

            if ax>=Cx&&ay>=Cy
                dx=R/30;dy=R/30;
            elseif ax>Cx&&ay<Cy
                dx=R/30;dy=-R/30;
            elseif ax<=Cx&&ay<=Cy
                dx=-R/30;dy=-R/30;
            elseif ax<Cx&&ay>Cy
                dx=-R/30; dy=R/30;
            end
      
            text(app.UIAxes,ax+dx,ay+dy,'X1','FontSize',16,'color',[1,0.39,0.28],'VerticalAlignment','middle','HorizontalAlignment','center',AffectAutoLimits="on")
            text(app.UIAxes,2*Cx-ax-dx,-ay-dy,'Y1','FontSize',16,'color',[1,0.39,0.28],'VerticalAlignment','middle','HorizontalAlignment','center',AffectAutoLimits="on")
            text(app.UIAxes,0.25*((app.m-Cx)*cos(Alphatimes2/2)-(app.n-Cy)*sin(Alphatimes2/2))+Cx,0.25*((app.n-Cy)*cos(Alphatimes2/2)+(app.m-Cx)*sin(Alphatimes2/2))+Cy,[num2str(Alphatimes2/pi*180),'° '],'color',[0,0,1],'FontSize',14,'VerticalAlignment','middle','HorizontalAlignment','center',AffectAutoLimits="on")
            %标记任一点应力状态
            app.Sigmax1.Value=num2str(roundn(real(ax),-2));
            app.taox1.Value=num2str(roundn(real(ay),-2));
            app.Sigmay1.Value=num2str(roundn(real(2*Cx-ax),-2));
            app.taoy1.Value=num2str(roundn(real(-ay),-2));
            rotaterec(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %预设上标
            app.UIFigure.WindowState="maximized";
            app.UIFigure.Name='ISSA (Interactive Stress State Analysis)';
            app.UIFigure.Icon='figure.png';
            %预画右下角微元
            axis(app.UIAxes2,'off');
            xlim(app.UIAxes2,[-1,1]);
            ylim(app.UIAxes2,[-1,1]);
            pbaspect(app.UIAxes2,[1,1,1]);
            rectangle(app.UIAxes2,'Position',[-0.5,-0.5,1,1],'EdgeColor',[1,1,1]);
            hold(app.UIAxes2,"on");
            quiver(app.UIAxes2,0.5*cos(0),0.5*sin(0),0.5*cos(0),0.5*sin(0),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.82,0.78,1.00]);
            quiver(app.UIAxes2,0.6*cos(0)-0.25*sin(0),0.6*sin(0)+0.25*cos(0),0.5*sin(0),-0.5*cos(0),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.58,0.69,1.00]);
            text(app.UIAxes2,0.5*cos(0+pi/20)+0.5*cos(0+pi/20),0.5*sin(0+pi/20)+0.5*sin(0+pi/20),'σx','Color',[0.82,0.78,1.00],'HorizontalAlignment','center','VerticalAlignment','middle','AffectAutoLimits','on');
            text(app.UIAxes2,0.6*cos(0-pi/20)+0.25*sin(0-pi/20),0.6*sin(0-pi/20)-0.25*cos(0-pi/20),'τxy','Color',[0.58,0.69,1.00],'HorizontalAlignment','left','VerticalAlignment','middle','AffectAutoLimits','on');
            quiver(app.UIAxes2,0.5*cos(pi/2),0.5*sin(pi/2),0.5*cos(pi/2),0.5*sin(pi/2),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.82,0.78,1.00]);
            quiver(app.UIAxes2,0.6*cos(pi/2)+0.25*sin(pi/2),0.6*sin(pi/2)-0.25*cos(pi/2),-0.5*sin(pi/2),0.5*cos(pi/2),'MaxHeadSize',1.5,'LineWidth',1,'Color',[0.58,0.69,1]);
            text(app.UIAxes2,0.5*cos(pi/2+pi/20)+0.5*cos(pi/2+pi/20),0.5*sin(pi/2+pi/20)+0.5*sin(pi/2+pi/20),'σy','Color',[0.82,0.78,1.00],'HorizontalAlignment','center','VerticalAlignment','middle','AffectAutoLimits','on');
            text(app.UIAxes2,0.6*cos(pi/2-pi/20)-0.3*sin(pi/2-pi/20),0.6*sin(pi/2-pi/20)+0.25*cos(pi/2-pi/20),'τyx','Color',[0.58,0.69,1],'HorizontalAlignment','right','VerticalAlignment','middle','AffectAutoLimits','on');
            set(app.UIAxes,'color',[0.88,0.91,0.97]);
        end

        % Value changed function: DEMONSTRATIONButton
        function DEMONSTRATIONButtonValueChanged(app, event)
            x1=app.State1x.Value;
            y1=app.State1y.Value;
            x2=app.State2x.Value;
            y2=app.State2y.Value;

            if x1==x2
                msgbox('Wrong Input:x1=x2!!!')
            else

                Cx=((x1-x2)*(x1+x2)-(y2-y1)*(y1+y2))/(2*(x1-x2));
                Cy=0;
                R=(y1^2+(((x1-x2)^2+(y2-y1)*(y1+y2))/(2*(x1-x2)))^2)^(1/2);

                %设置坐标轴范围
                xlim(app.UIAxes,[Cx-5/4*R,Cx+5/4*R]);
                ylim(app.UIAxes,[Cy-5/4*R,Cy+5/4*R]);
     
                %动画展示画图步骤
                    %p1：展示输入的两个点E、F
                process1=scatter(app.UIAxes,[x1,x2],[y1,y2],40,[0,0,0],'filled');
                text(app.UIAxes,x1,y1,'E','FontSize',16,'color',[0.42,0.35,0.80],'VerticalAlignment','bottom','HorizontalAlignment','left',AffectAutoLimits="on");
                text(app.UIAxes,x2,y2,'F','FontSize',16,'color',[0.53,0.15,0.34],'VerticalAlignment','bottom','HorizontalAlignment','left',AffectAutoLimits="on");
                pause(0.5);
                
                    %p2：连接两点
                s1=((x1-x2)^2+(y1-y2)^2)^(1/2); %步长（分为100份）
                for j=0:s1/100:s1
                    process2=line(app.UIAxes,[x1,x1-(x1-x2)/s1*j],[y1,y1-(y1-y2)/s1*j],'Linewidth',1.2,'color',[0.16,0.14,0.13]);
                    pause(0.0005);
                end
                pause(0.5);

                    %p3：作中垂线
                s2=(((x1+x2)/2-Cx)^2+((y1+y2)/2-Cy)^2)^(1/2); %步长（分为100份）
                for j=0:s2/100:s2
                    process3=line(app.UIAxes,[(x1+x2)/2,(x1+x2)/2-((x1+x2)/2-Cx)/s2*j],[(y1+y2)/2,(y1+y2)/2-((y1+y2)/2-Cy)/s2*j],'Linewidth', 1.2,'color',[0.64,0.58,0.5]);
                    pause(0.0005);
                end
                pause(0.5);

                    %p4：连接半径
                s3=((x1-Cx)^2+(y1-Cy)^2)^(1/2); %步长（分为100份）
                for j=0:s3/100:s3
                    process4=line(app.UIAxes,[Cx,Cx-(Cx-x1)/s3*j],[Cy,Cy-(Cy-y1)/s3*j],'Linewidth', 1.2,'color',[0.24,0.35,0.67]);
                    pause(0.0005);
                end
                pause(0.5);

                    %process5-6：演示画圆 （旋转半径+作圆）
                for i=0:0.01:2*pi
                    %p5：画旋转的半径
                    xp5=(x1-Cx)*cos(i)-(y1-Cy)*sin(i)+Cx;
                    yp5=(y1-Cy)*cos(i)+(x1-Cx)*sin(i)+Cy;
                    process5=line(app.UIAxes,[Cx,xp5],[Cy,yp5],'Linewidth', 1.2,'color',[0.44,0.5,0.41]);
                    
                    %p6：作圆
                    xp6=(x1-Cx)*cos(i+0.01)-(y1-Cy)*sin(i+0.01)+Cx;
                    yp6=(y1-Cy)*cos(i+0.01)+(x1-Cx)*sin(i+0.01)+Cy;
                    process6=line(app.UIAxes,[xp5,xp6],[yp5,yp6],'color',[0.1,0.1,0.44],'Linewidth', 1.5);
                    
                    pause(0.0005);
                    delete(process5);
                end

                %删除动画
                if s2==0
                    delete(process1);
                    delete(process2);
                    delete(process4);
                    delete(process6);
                else
                    delete(process1);
                    delete(process2);
                    delete(process3);
                    delete(process4);
                    delete(process6);
                end

                %常规作图
                RegularFunction(app);
            end     
        end

        % Value changed function: DRAWINGButton
        function DRAWINGButtonValueChanged2(app, event)
            x1=app.State1x.Value;
            x2=app.State2x.Value;
            
            %输入两点坐标
            if x1==x2 %判断输入是否正确
                msgbox('Wrong Input:x1=x2!!!')
            else         
            RegularFunction(app);
            end
        end

        % Value changed function: ANALYSISButton
        function ANALYSISButtonValueChanged(app, event)
            x1=app.State1x.Value;
            y1=app.State1y.Value;
            x2=app.State2x.Value;
            y2=app.State2y.Value;
            if x1==x2
                msgbox('Wrong Input:x1=x2!!!')
            else

            
                Cx=((x1-x2)*(x1+x2)-(y2-y1)*(y1+y2))/(2*(x1-x2));
                Cy=0;
            
                ECx=2*(app.angleECx.Value)*pi/180;
                xx=(x1-Cx)*cos(ECx)-(y1-Cy)*sin(ECx)+Cx;
                xy=(y1-Cy)*cos(ECx)+(x1-Cx)*sin(ECx)+Cy;
            
                RegularFunction(app);

                SelectBottom=app.RotationButtonGroup.SelectedObject;
                Alphatimes2=2*(app.RotationAngleACWEditField.Value)*pi/180;

                if Alphatimes2~=0
                    switch SelectBottom.Text
                        case 'CA'
                            app.m=xx;
                            app.n=xy;
                            RegularRotateAnalysis(app);
                        case 'CE'
                            app.m=x1;
                            app.n=y1;
                            RegularRotateAnalysis(app);

                        case 'CF'
                            app.m=x2;
                            app.n=y2;
                            RegularRotateAnalysis(app);
                    end
                end
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            ISSAEntry;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {727, 727, 727};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {727, 727};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {226, '1x', 226};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.KnownStressStatesPanel = uipanel(app.GridLayout4);
            app.KnownStressStatesPanel.Title = 'Known Stress States';
            app.Point1EditFieldLabel = uilabel(app.KnownStressStatesPanel);
            app.Point1EditFieldLabel.Text = 'State 1 (E)';
            app.State1x = uieditfield(app.KnownStressStatesPanel, 'numeric');
            app.State1y = uieditfield(app.KnownStressStatesPanel, 'numeric');
            app.Point1EditFieldLabel_2 = uilabel(app.KnownStressStatesPanel);
            app.Point1EditFieldLabel_2.Text = 'State 2 (F)';
            app.State2x = uieditfield(app.KnownStressStatesPanel, 'numeric');
            app.State2y = uieditfield(app.KnownStressStatesPanel, 'numeric');
            app.xOELabel = uilabel(app.KnownStressStatesPanel);
            app.xOELabel.Text = '∠ECx (°, ACW)';
            app.angleECx = uieditfield(app.KnownStressStatesPanel, 'numeric');
            app.DRAWINGButton = uibutton(app.KnownStressStatesPanel, 'state');
            app.DRAWINGButton.ValueChangedFcn = createCallbackFcn(app, @DRAWINGButtonValueChanged2, true);
            app.DRAWINGButton.Text = 'DRAWING';
            app.DEMONSTRATIONButton = uibutton(app.KnownStressStatesPanel, 'state');
            app.DEMONSTRATIONButton.ValueChangedFcn = createCallbackFcn(app, @DEMONSTRATIONButtonValueChanged, true);
            app.DEMONSTRATIONButton.Text = 'DEMONSTRATION';
            app.StressStateAnalysisPanel = uipanel(app.GridLayout4);
            app.StressStateAnalysisPanel.Title = 'Stress State Analysis';
            app.RotationButtonGroup = uibuttongroup(app.StressStateAnalysisPanel);
            app.RotationButtonGroup.Title = 'Rotation';
            app.CAButton = uiradiobutton(app.RotationButtonGroup);
            app.CAButton.Text = 'CA';
            app.CAButton.Value = true;
            app.CEButton = uiradiobutton(app.RotationButtonGroup);
            app.CEButton.Text = 'CE';
            app.CFButton = uiradiobutton(app.RotationButtonGroup);
            app.CFButton.Text = 'CF';
            app.RotationAngleACWEditFieldLabel = uilabel(app.StressStateAnalysisPanel);
            app.RotationAngleACWEditFieldLabel.Text = 'Rotation Angle α (°, ACW)';
            app.RotationAngleACWEditField = uieditfield(app.StressStateAnalysisPanel, 'numeric');
            app.ANALYSISButton = uibutton(app.StressStateAnalysisPanel, 'state');
            app.ANALYSISButton.ValueChangedFcn = createCallbackFcn(app, @ANALYSISButtonValueChanged, true);
            app.ANALYSISButton.Text = 'ANALYSIS';
            app.Image5 = uiimage(app.GridLayout4);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '指示图.png');
            app.EmailISSAhelper2024outlookcomLabel = uilabel(app.GridLayout4);
            app.EmailISSAhelper2024outlookcomLabel.Text = 'Email: ISSAhelper2024@outlook.com';
            app.ISSALabel = uilabel(app.GridLayout4);
            app.ISSALabel.Text = 'ISSA';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout5);
            xlabel(app.UIAxes, 'σ')
            ylabel(app.UIAxes, 'τ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.MohrsCircleLabel = uilabel(app.GridLayout5);
            app.MohrsCircleLabel.Text = 'Mohr''s Circle';
            app.DesignerHongkaiTaoQiZhaoJiayanWangChengyangZhuJingranLiLabel = uilabel(app.GridLayout5);
            app.DesignerHongkaiTaoQiZhaoJiayanWangChengyangZhuJingranLiLabel.Text = 'Designer: Hongkai Tao, Qi Zhao, Jiayan Wang, Chengyang Zhu, Jingran Li';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes2 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-1 1];
            app.UIAxes2.YLim = [-1 1];
            app.UIAxes2.XTick = [-1 0 1];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [-1 0 1];
            app.UIAxes2.YTickLabel = '';
            app.Panel_3 = uipanel(app.GridLayout3);
            app.Panel_2 = uipanel(app.GridLayout3);
            app.Panel = uipanel(app.GridLayout3);
            app.FCxLabel = uilabel(app.GridLayout3);
            app.FCxLabel.Text = '∠FCx';
            app.FCxLabel_2 = uilabel(app.GridLayout3);
            app.FCxLabel_2.Text = 'θ1';
            app.FCx180Label = uilabel(app.GridLayout3);
            app.FCx180Label.Text = '0 ≤ ∠FCx ≤ 180°';
            app.AnalyticalLabel = uilabel(app.GridLayout3);
            app.AnalyticalLabel.Text = 'Analytical';
            app.GraphicalLabel = uilabel(app.GridLayout3);
            app.GraphicalLabel.Text = 'Graphical';
            app.AngleLabel = uilabel(app.GridLayout3);
            app.AngleLabel.Text = 'Angle';
            app.FCxgraphical = uitextarea(app.GridLayout3);
            app.FCxanalytical = uitextarea(app.GridLayout3);
            app.Theta1analytical = uitextarea(app.GridLayout3);
            app.FCxLabel_3 = uilabel(app.GridLayout3);
            app.FCxLabel_3.Text = 'θ2';
            app.Theta2graphical = uitextarea(app.GridLayout3);
            app.Theta2analytical = uitextarea(app.GridLayout3);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '-180° ≤ θ1,θ2 ≤ 180°';
            app.EditFieldLabel = uilabel(app.GridLayout3);
            app.EditFieldLabel.Text = 'σ2';
            app.Stress2 = uieditfield(app.GridLayout3, 'numeric');
            app.PointDEditFieldLabel = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel.Text = 'Point X1';
            app.EditField_2Label = uilabel(app.GridLayout3);
            app.EditField_2Label.Text = 'σ3';
            app.Stress3 = uieditfield(app.GridLayout3, 'numeric');
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = 'σ1';
            app.Stress1 = uieditfield(app.GridLayout3, 'numeric');
            app.Shearmax = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_2Label_2 = uilabel(app.GridLayout3);
            app.EditField_2Label_2.Text = 'τmax';
            app.Shearmin = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_2Label_3 = uilabel(app.GridLayout3);
            app.EditField_2Label_3.Text = 'τmin';
            app.PointDEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel_2.Text = 'σx';
            app.PointDEditFieldLabel_3 = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel_3.Text = 'τxy';
            app.Sigmax1 = uitextarea(app.GridLayout3);
            app.taox1 = uitextarea(app.GridLayout3);
            app.PointAEditFieldLabel = uilabel(app.GridLayout3);
            app.PointAEditFieldLabel.Text = 'Point A';
            app.Sigmax = uitextarea(app.GridLayout3);
            app.taoxy = uitextarea(app.GridLayout3);
            app.PointDEditFieldLabel_4 = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel_4.Text = 'σx1';
            app.PointDEditFieldLabel_5 = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel_5.Text = 'τx1';
            app.PointDEditFieldLabel_6 = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel_6.Text = 'Point Y1';
            app.PointDEditFieldLabel_7 = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel_7.Text = 'σy1';
            app.PointDEditFieldLabel_8 = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel_8.Text = 'τy1';
            app.Sigmay1 = uitextarea(app.GridLayout3);
            app.taoy1 = uitextarea(app.GridLayout3);
            app.PointDEditFieldLabel_9 = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel_9.Text = 'σy';
            app.PointDEditFieldLabel_10 = uilabel(app.GridLayout3);
            app.PointDEditFieldLabel_10.Text = 'τyx';
            app.PointAEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.PointAEditFieldLabel_2.Text = 'Point B';
            app.Sigmay = uitextarea(app.GridLayout3);
            app.taoyx = uitextarea(app.GridLayout3);
            app.InfinitesimalElementLabel = uilabel(app.GridLayout3);
            app.InfinitesimalElementLabel.Text = 'Infinitesimal Element';
            app.Theta1graphical = uitextarea(app.GridLayout3);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISSAAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end